classdef avatar_arm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Panel                matlab.ui.container.Panel
        CalibrateButton      matlab.ui.control.Button
        EditField            matlab.ui.control.NumericEditField
        ConnectSwitchLabel   matlab.ui.control.Label
        ConnectSwitch        matlab.ui.control.RockerSwitch
        GOSwitchLabel        matlab.ui.control.Label
        GOSwitch             matlab.ui.control.RockerSwitch
        SerialDropDownLabel  matlab.ui.control.Label
        SerialDropDown       matlab.ui.control.DropDown
        BitrateEditField     matlab.ui.control.NumericEditField
        ArmButton            matlab.ui.control.Button
    end

        
    properties (Access = private)
        
        coords_count = 6;
        actuators_gui = []
        coords_gui = []
        tmp = 0;
        loopTimer = [];
        aliveTimer = [];
        machineState = 'clean';
        machineCommand = 'stop';
        serial=[]
        proto = avatar_proto();
        timeBegnRequest = 0;
        timeOut = 1.1;
        remoteStates = [
 			'READY'...% = 1 // — манипулятор готов к работе
			, 'NOT_CALIBRATED'...% = 2 — манипулятор не откалиброван
			, 'CALIBRATING'...% = 3  — манипулятор калибруется
			,   'SHUTTING_DOWN'...% = 4 — манипулятор выключается (после команды SHUTDOWN)
			, 'OFF'...% = 5 — манипулятор готов к выключению (после команды SHUTDOWN)
			, 'MOVING'...% = 6 — манипулятор выполняет движение
			, 'FAIL'...% = 7— на манипуляторе обнаружена устранимая ошибка (требуется посылка команды RESET)
			, 'ERROR'...% =8 — на манипуляторе обнаружена неустранимая ошибка. Требуется перезагрузить манипулятор (нужно сначала попробовать команду REBOOT 
			, 'POWER_OFF'...% = 9 — у манипулятора вырублено питание приводов
			, 'POWER_ON'...% = 10 — у манипулятора вырублено питание приводов]
       ];
    end
    
    methods (Access = private)
     
        function reset_coords_gui(app,n)
            app.coords_count = n;
            if ~isempty( app.coords_gui)
                for i=1:app.coords_count
                    if ~isempty( app.coords_gui{i} )
                        delete(app.coords_gui{i});
                        app.coords_gui{i} = [];
                    end
                end
            end
            app.coords_gui = cell(app.coords_count,1);
        end
        
        function aliveLoop(app, varargin)
            app.EditField.Value = app.EditField.Value + 1;
        end


        function result = timerActive(app)
            result = ~isempty( app.loopTimer );
        end
        function machineStart(app)
            app.machineCommand = 'start';
        end
        function machineStop(app)
            app.machineCommand = 'stop';
        end
        function protoConfig(app,data)
            n = length(data.coords);
            
            app.reset_coords_gui(n);
            
            x = 120;
            y = app.UIFigure.Position(4) +app.tmp - 65;

            for i=1:n
                app.coords_gui{i} = avatar_coord(app, x, y, i, data.coords(i));
                y = y - 62;
            end       
           app.machineState = data.status;
        end
        function protoStatus(app,data)
            app.coords_gui{1}.setActual(data.data.arm.pos.x);
            app.coords_gui{2}.setActual(data.data.arm.pos.y);
            app.coords_gui{3}.setActual(data.data.arm.pos.z);
            app.coords_gui{4}.setActual(data.data.arm.rot.y);
            app.coords_gui{5}.setActual(data.data.arm.rot.p);
            app.coords_gui{6}.setActual(data.data.arm.rot.r);
            app.coords_gui{7}.setActual(data.data.arm.fingers(1));
            app.machineState = data.data.status; %app.remoteStates(data.data.status);
        end
        function machineLoop(app, varargin)
            switch app.machineState
                case 'clean'
                    write( app.serial, app.proto.queryConfig() , "uint8" );
                    app.machineState = 'waitConfig';
                    tic();
                case 'waitConfig'
                    tm=toc();
                    if tm > app.timeOut
                        app.machineState = 'clean'; 
                    end;
                otherwise
                    tm=toc();
                    if tm > 0.1
                        if ~strcmp( app.machineCommand,'start' )
                            write( app.serial, app.proto.queryStatus() , "uint8" );                        
                        else
                            write( app.serial, app.proto.commandArm( ...
                            app.coords_gui{1}.required...
                            , app.coords_gui{2}.required...
                            , app.coords_gui{3}.required...
                            , app.coords_gui{4}.required...
                            , app.coords_gui{5}.required...
                            , app.coords_gui{6}.required...
                            , app.coords_gui{7}.required...
                            ) , "uint8" );                
                        end
                        tic();
                    end;
            end
        end
                
        function serialRead(app, src,evt)
            while(src.NumBytesAvailable>0)
                json = readline(src);
                try
                    packet = jsondecode(json);
                    switch packet.command
                        case 'CONFIG'
                            if packet.status ~= 0                                
                                app.protoConfig(packet);
                            end
                        case 'STATUS'
                            if ~( strcmp(app.machineState,'clean') || strcmp(app.machineState,'waitConfig') )
                                 app.protoStatus(packet);
                            end
                        otherwise
                            disp('uncnown packet');
                    end
                catch ME
                end
            end
        end
        
        function timerOpen(app)
            app.timerClose();

            app.serial = serialport(app.SerialDropDown.Value,app.BitrateEditField.Value);
            configureTerminator(app.serial,10)
            configureCallback(app.serial,"terminator",@app.serialRead)
            
            app.loopTimer = timer('ExecutionMode', 'FixedRate', ...
            'Period', 0.1, ...
            'TimerFcn', @app.machineLoop );
            start(app.loopTimer );                                    
            app.aliveTimer = timer('ExecutionMode', 'FixedRate', ...
            'Period', 1, ...
            'TimerFcn', @app.aliveLoop );
            start(app.aliveTimer );                                    
        end
        function timerClose(app)
            if ~isempty( app.loopTimer )
                app.loopTimer.stop();
                delete (app.loopTimer);
                app.loopTimer = [];
            end
            if ~isempty( app.aliveTimer )
                app.aliveTimer.stop();
                delete (app.aliveTimer);
                app.aliveTimer = [];
            end
            if ~isempty ( app.serial)
                delete (app.serial)
                app.serial = [];
            end
            app.machineStop();
            app.machineState();
        end
        function updateConnectionBtn(app)
            if app.timerActive
                app.ConnectSwitchLabel.Text = 'Disconnect';
                app.ConnectSwitchLabel.BackgroundColor = [0 1 0];
                app.ConnectSwitchLabel.FontColor = [0 0 0];
            else
                app.ConnectSwitchLabel.BackgroundColor = [0.1 0.3 0.1];
                app.ConnectSwitchLabel.FontColor = [0.9 1 0.9];
                app.ConnectSwitchLabel.Text = 'Connect';
            end            
        end
        function updateGOBtn(app)
            if app.timerActive
                app.GOSwitchLabel.Enable = 1;
                if strcmp(app.machineCommand,'start')
                    app.GOSwitchLabel.Text = 'X STOP X';
                    app.GOSwitchLabel.BackgroundColor = [1 0 0];
                    app.GOSwitchLabel.FontColor = [0 0 0];
                else
                    app.GOSwitchLabel.BackgroundColor = [0.1 0.3 0.1];
                    app.GOSwitchLabel.FontColor = [0.9 1 0.9];
                    app.GOSwitchLabel.Text = 'GO';
                end
            else
                app.GOSwitchLabel.BackgroundColor = [0.3 0.3 0.3];
                app.GOSwitchLabel.FontColor = [0.7 0.7 0.7];
                app.GOSwitchLabel.Text = 'GO';
                app.GOSwitchLabel.Enable = 0;
            end            
        end
        
        function updateInterface(app)
            app.updateConnectionBtn();
            app.updateGOBtn();            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             app.SerialDropDown.Items = ['xxxx', serialportlist("available") ];            
            ini = IniConfig();
            ini.ReadFile('avatar_arm.ini');            
            tmp =ini.GetValues('SETTINGS','SERIAL','xxxx');
            if any(strcmp(app.SerialDropDown.Items,tmp))
                app.SerialDropDown.Value = tmp;
            end
            app.BitrateEditField.Value = ini.GetValues('SETTINGS','BAUDRATE','9600');
            app.updateInterface();
        end

        % Button pushed function: CalibrateButton
        function CalibrateButtonPushed2(app, event)
            write( app.serial, app.proto.commandCalibrate() , "uint8" );    
        end

        % Value changed function: ConnectSwitch
        function ConnectSwitchValueChanged(app, event)
            value = app.ConnectSwitch.Value;
            if strcmp( value, 'Off')
                app.timerClose();
                app.GOSwitch.Value = 'Off';
            else
                app.timerOpen();
            end     
            app.updateInterface();
        end

        % Value changed function: GOSwitch
        function GOSwitchValueChanged(app, event)
            value = app.GOSwitch.Value;
            if strcmp( value, 'Off')
                app.machineStop();
            else
                if app.timerActive()
                    app.machineStart();
                end
            end              
            app.updateInterface();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            ini = IniConfig();
            ini.ReadFile('avatar_arm.ini');            
            ini.SetValues('SETTINGS','SERIAL',app.SerialDropDown.Value);
            ini.SetValues('SETTINGS','BAUDRATE',app.BitrateEditField.Value);
            ini.WriteFile('avatar_arm.ini');            
            delete(app)
            
        end

        % Button pushed function: ArmButton
        function ArmButtonPushed(app, event)
            write( app.serial, app.proto.commandArm( ...
            app.coords_gui{1}.required...
            , app.coords_gui{2}.required...
            , app.coords_gui{3}.required...
            , app.coords_gui{4}.required...
            , app.coords_gui{5}.required...
            , app.coords_gui{6}.required...
            , app.coords_gui{7}.required...
            ) , "uint8" );                
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Panel = uipanel(app.UIFigure);
            app.CalibrateButton = uibutton(app.Panel, 'push');
            app.CalibrateButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateButtonPushed2, true);
            app.CalibrateButton.Text = 'Calibrate';
            app.EditField = uieditfield(app.Panel, 'numeric');
            app.EditField.Editable = 'off';
            app.ConnectSwitchLabel = uilabel(app.Panel);
            app.ConnectSwitchLabel.Text = 'Connect';
            app.ConnectSwitch = uiswitch(app.Panel, 'rocker');
            app.ConnectSwitch.ValueChangedFcn = createCallbackFcn(app, @ConnectSwitchValueChanged, true);
            app.GOSwitchLabel = uilabel(app.Panel);
            app.GOSwitchLabel.Text = 'GO';
            app.GOSwitch = uiswitch(app.Panel, 'rocker');
            app.GOSwitch.ValueChangedFcn = createCallbackFcn(app, @GOSwitchValueChanged, true);
            app.SerialDropDownLabel = uilabel(app.Panel);
            app.SerialDropDownLabel.Text = 'Serial';
            app.SerialDropDown = uidropdown(app.Panel);
            app.BitrateEditField = uieditfield(app.Panel, 'numeric');
            app.BitrateEditField.Limits = [0 1000000];
            app.BitrateEditField.ValueDisplayFormat = '%2.2f';
            app.BitrateEditField.Value = 9600;
            app.ArmButton = uibutton(app.Panel, 'push');
            app.ArmButton.ButtonPushedFcn = createCallbackFcn(app, @ArmButtonPushed, true);
            app.ArmButton.Text = 'Arm';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = avatar_arm

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end